classdef tremorLabeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SyncdataButton            matlab.ui.control.Button
        StartlabelButton          matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        FilesTab                  matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        LabelingTab               matlab.ui.container.Tab
        AddchunkButton            matlab.ui.control.Button
        RemovechunkButton         matlab.ui.control.Button
        PHTButton                 matlab.ui.control.Button
        MovementButton            matlab.ui.control.Button
        StaticButton              matlab.ui.control.Button
        ClassifychunkLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        FinishlabelButton         matlab.ui.control.Button
        SelectedmeasurementLabel  matlab.ui.control.Label
        emptyLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        measurements % All measurements
        measurement % Selected measurements
        ROI % ROI for labeling
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.measurements = sync_measurement_data("Research");
            N = length(app.measurements);
            
            data = {N};
            
            for i=1:N
                data{i, 1} = app.measurements{i}.fileName;
                data{i, 2} = app.measurements{i}.isLabeled;
            end
            
            app.UITable.Data = data;
        end

        % Button pushed function: SyncdataButton
        function SyncdataButtonPushed(app, event)
            app.measurements = sync_measurement_data("Research");
            N = length(app.measurements);
            
            data = {N};
            
            for i=1:N
                data{i, 1} = app.measurements{i}.fileName;
                data{i, 2} = app.measurements{i}.isLabeled;
            end
            
            app.UITable.Data = data;
        end

        % Button pushed function: StartlabelButton
        function StartlabelButtonPushed(app, event)
            if isprop(app, 'measurement')
                app.measurement.fileName
                app.emptyLabel.Text = app.measurement.fileName;

                plot(app.UIAxes, [0 size(app.measurement.accData, 1)/100], [0 0], 'color', 'black', 'linewidth', 3);
                app.TabGroup.SelectedTab = app.LabelingTab;
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.measurement = app.measurements{event.Indices(1)};
        end

        % Button pushed function: AddchunkButton
        function AddchunkButtonPushed(app, event)
            app.ROI = [app.ROI, drawrectangle(app.UIAxes, 'Color', 'b', 'Position', [0, -1, 5, 2])];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SyncdataButton = uibutton(app.UIFigure, 'push');
            app.SyncdataButton.ButtonPushedFcn = createCallbackFcn(app, @SyncdataButtonPushed, true);
            app.SyncdataButton.Text = 'Sync data';
            app.StartlabelButton = uibutton(app.UIFigure, 'push');
            app.StartlabelButton.ButtonPushedFcn = createCallbackFcn(app, @StartlabelButtonPushed, true);
            app.StartlabelButton.Text = 'Start label';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.UITable = uitable(app.FilesTab);
            app.UITable.ColumnName = {'Filename'; 'Is labeled'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.LabelingTab = uitab(app.TabGroup);
            app.LabelingTab.Title = 'Labeling';
            app.AddchunkButton = uibutton(app.LabelingTab, 'push');
            app.AddchunkButton.ButtonPushedFcn = createCallbackFcn(app, @AddchunkButtonPushed, true);
            app.AddchunkButton.Text = 'Add chunk';
            app.RemovechunkButton = uibutton(app.LabelingTab, 'push');
            app.RemovechunkButton.Text = 'Remove chunk';
            app.PHTButton = uibutton(app.LabelingTab, 'push');
            app.PHTButton.Text = 'PHT';
            app.MovementButton = uibutton(app.LabelingTab, 'push');
            app.MovementButton.Text = 'Movement';
            app.StaticButton = uibutton(app.LabelingTab, 'push');
            app.StaticButton.Text = 'Static';
            app.ClassifychunkLabel = uilabel(app.LabelingTab);
            app.ClassifychunkLabel.Text = 'Classify chunk:';
            app.UIAxes = uiaxes(app.LabelingTab);
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.FinishlabelButton = uibutton(app.UIFigure, 'push');
            app.FinishlabelButton.Text = 'Finish label';
            app.SelectedmeasurementLabel = uilabel(app.UIFigure);
            app.SelectedmeasurementLabel.Text = 'Selected measurement: ';
            app.emptyLabel = uilabel(app.UIFigure);
            app.emptyLabel.Text = 'empty';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tremorLabeling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end